classdef demoautocorr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxesSignal              matlab.ui.control.UIAxes
        UIAxesACF                 matlab.ui.control.UIAxes
        Slider_3                  matlab.ui.control.Slider
        Slider_3Label             matlab.ui.control.Label
        FourierSeriesLengthLabel  matlab.ui.control.Label
        SamplingFrequencyHzLabel  matlab.ui.control.Label
        Slider_5                  matlab.ui.control.Slider
        Slider_5Label             matlab.ui.control.Label
        SignalLengthsLabel        matlab.ui.control.Label
        Slider_2                  matlab.ui.control.Slider
        Slider_2Label             matlab.ui.control.Label
        AmplitudeVLabel           matlab.ui.control.Label
        Slider_4                  matlab.ui.control.Slider
        Slider_4Label             matlab.ui.control.Label
        ACFSettingsButtonGroup    matlab.ui.container.ButtonGroup
        XCORRMatlabButton         matlab.ui.control.RadioButton
        ACFOwnButton              matlab.ui.control.RadioButton
        FastACFOwnButton          matlab.ui.control.RadioButton
        NoiseUDNCheckBox          matlab.ui.control.CheckBox
    end


    methods (Access = public)
    
        function updatePlot(app)
            [x,y1] = generateFourierSeries(app);
            y2 = SelectFunction(app,y1);

            plot(app.UIAxesSignal,x,y1);
            plot(app.UIAxesACF,y2);
        end
        
        function [x,y] = generateFourierSeries(app);
            fs = app.Slider_5.Value;
            k = app.Slider_3.Value;
            sl = app.Slider_2.Value;
            h = app.Slider_4.Value;
            n = app.NoiseUDNCheckBox.Value;
            
            x = 0:1/fs:sl;
            y = 0;
            
            for i = 1:2:k
                y = y + (sin(2*pi*i*x)/i);
            end
            y = y*(4*h/pi);
                       
            if n
                y = awgn(y,5)
            end
   
        end
        
        function y = myACF(app, x)          
            y = zeros(1,2*length(x)-1);
            
            for i = 1:length(y)               
                if i <= length(x) 
                    y(i) = sum(x(1,length(x)-i+1:length(x)).*x(1,1:i)); 
                else 
                    j = i-length(x); 
                    y(i) = sum(x(1,1:length(x)-j).*x(1,j+1:length(x))); 
                end           
            end         
        end
        
        function y = myFastACF(app, x)
            len = (length(x)*2-1);
            
            ft = fft(x,len);
            cft = conj(ft);
            
            y = ifft(ft.*cft,len);
            y = [y((len/2):end) y(1:(len/2))]
        end
        
        function y = SelectFunction(app, x)
            value = app.ACFSettingsButtonGroup.SelectedObject.Text
                
            switch value
                case 'XCORR (Matlab)'
                    y = xcorr(x);
                case 'ACF (Own)'
                    y = myACF(app, x);
                case 'Fast-ACF (Own)'
                    y = myFastACF(app, x);
            end
        end
                
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updatePlot(app);
        end

        % Value changed function: Slider_5
        function SampleFrequencyChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Slider_4
        function AmplitudeChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Slider_3
        function FourierSeriesLengthChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Slider_2
        function SignalLengthChanged(app, event)
            updatePlot(app);    
        end

        % Value changed function: NoiseUDNCheckBox
        function NoiseUDNChanged(app, event)
            updatePlot(app);  
        end

        % Selection changed function: ACFSettingsButtonGroup
        function ACFMethodChanged(app, event)
            updatePlot(app);
        end

        % Callback function
        function NoiseLevelChanged(app, event)
            updatePlot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesSignal = uiaxes(app.UIFigure);
            title(app.UIAxesSignal, 'Square-Wave-Signal')
            xlabel(app.UIAxesSignal, 't')
            ylabel(app.UIAxesSignal, 'S(t)')
            app.UIAxesACF = uiaxes(app.UIFigure);
            title(app.UIAxesACF, 'Autocorrelation-Function')
            xlabel(app.UIAxesACF, 'f')
            ylabel(app.UIAxesACF, 'S(f)')
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [1 21];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @FourierSeriesLengthChanged, true);
            app.Slider_3.Value = 1;
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = '';
            app.FourierSeriesLengthLabel = uilabel(app.UIFigure);
            app.FourierSeriesLengthLabel.Text = 'Fourier-Series-Length';
            app.SamplingFrequencyHzLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyHzLabel.Text = 'Sampling-Frequency (Hz)';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [1 1000];
            app.Slider_5.MajorTicks = [1 500 1000];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @SampleFrequencyChanged, true);
            app.Slider_5.Value = 1;
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = '';
            app.SignalLengthsLabel = uilabel(app.UIFigure);
            app.SignalLengthsLabel.Text = 'Signal-Length (s)';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [1 10];
            app.Slider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider_2.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '10'};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @SignalLengthChanged, true);
            app.Slider_2.Value = 1;
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '';
            app.AmplitudeVLabel = uilabel(app.UIFigure);
            app.AmplitudeVLabel.Text = 'Amplitude (V)';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [1 10];
            app.Slider_4.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider_4.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '10'};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @AmplitudeChanged, true);
            app.Slider_4.Value = 1;
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = '';
            app.ACFSettingsButtonGroup = uibuttongroup(app.UIFigure);
            app.ACFSettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ACFMethodChanged, true);
            app.ACFSettingsButtonGroup.Title = 'ACF-Settings';
            app.XCORRMatlabButton = uiradiobutton(app.ACFSettingsButtonGroup);
            app.XCORRMatlabButton.Text = 'XCORR (Matlab)';
            app.ACFOwnButton = uiradiobutton(app.ACFSettingsButtonGroup);
            app.ACFOwnButton.Text = 'ACF (Own)';
            app.ACFOwnButton.Value = true;
            app.FastACFOwnButton = uiradiobutton(app.ACFSettingsButtonGroup);
            app.FastACFOwnButton.Text = 'Fast-ACF (Own)';
            app.NoiseUDNCheckBox = uicheckbox(app.UIFigure);
            app.NoiseUDNCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoiseUDNChanged, true);
            app.NoiseUDNCheckBox.Text = 'Noise-UDN';
        end
    end

    methods (Access = public)

        % Construct app
        function app = demoautocorr

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end