classdef demoresample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FsSigAxes                 matlab.ui.control.UIAxes
        FsSpAxes                  matlab.ui.control.UIAxes
        UpSigAxes                 matlab.ui.control.UIAxes
        UpSpAxes                  matlab.ui.control.UIAxes
        DoSigAxes                 matlab.ui.control.UIAxes
        DoSpAxes                  matlab.ui.control.UIAxes
        FsLSlider                 matlab.ui.control.Slider
        FourierSeriesLengthLabel  matlab.ui.control.Label
        UpFacSlider               matlab.ui.control.Slider
        UpSamplingFactorLabel     matlab.ui.control.Label
        DowFacSlider              matlab.ui.control.Slider
        DownSamplingFactorLabel   matlab.ui.control.Label
        InterpolationCheckBox     matlab.ui.control.CheckBox
        DecimationCheckBox        matlab.ui.control.CheckBox
    end

    % Date      :   2017-12-08
    % Author    :   Niko Jozic
    % Course    :   AISE-M DSP1
    
    methods (Access = private)
        
        function update(app)
         
            [x1,y1] = generateFourierSeries(app);
            y2 = upSampling(app,y1,ceil(app.UpFacSlider.Value));
            y3 = downsample(y1,ceil(app.DowFacSlider.Value));
            
            % Row 1
            stem(app.FsSigAxes,x1,y1);
            plot(app.FsSpAxes,abs(fft(y1)));
                      
            % Row 2
            if(app.InterpolationCheckBox.Value)

                app.UpFacSlider.Enable = 'on';
                stem(app.UpSigAxes,y2)
                plot(app.UpSpAxes,abs(fft(y2))); 
            else
                cla(app.UpSigAxes);
                cla(app.UpSpAxes);
                app.UpFacSlider.Enable = 'off';
            end

            % Row 3
            if(app.DecimationCheckBox.Value)
                app.DowFacSlider.Enable = 'on';
                stem(app.DoSigAxes,y3)
                plot(app.DoSpAxes,abs(fft(y3)));
            else
                app.DowFacSlider.Enable = 'off';
                cla(app.DoSigAxes);
                cla(app.DoSpAxes);
                
            end
        end
        
        function [x,y] = generateFourierSeries(app);
            f = 5;         
            fs = 100;       
            h = 1;
            sl = 1;        
            k = app.FsLSlider.Value;
                       
            x = 0:1/fs:sl;
            y = 0;
            
            for i = 1:2:k
                y = y + (sin(2*pi*i*f*x)/i);
            end
            
            y = y*(4*h/pi); 
        end
        
        function g = upSampling(app, y, fac)
            
%             g = upsample(y,fac);

            g=zeros(1,length(y)*fac);
            for i=1:length(y)
                g(fac*i)=y(i);
            end 
            
            low_filt = fir1(30, 0.1,'low');
            g = filter(low_filt,1,g);
        end
            
    end


    methods (Access = private)

        % Code that executes after component creation
        function initFcn(app)
            update(app);
        end

        % Value changed function: FsLSlider
        function FsLValueChanged(app, event)
            update(app);
        end

        % Value changed function: UpFacSlider
        function UpFacValueChanged(app, event)
            update(app);
        end

        % Value changed function: DowFacSlider
        function DowFacValueChanged(app, event)
            update(app);
        end

        % Value changed function: InterpolationCheckBox
        function IntCheckBoxValueChanged(app, event)
            update(app);
        end

        % Value changed function: DecimationCheckBox
        function DecCheckBoxValueChanged(app, event)
            update(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FsSigAxes = uiaxes(app.UIFigure);
            title(app.FsSigAxes, 'Fourier-Series-Signal')
            xlabel(app.FsSigAxes, 't')
            ylabel(app.FsSigAxes, 'S(t)')
            app.FsSpAxes = uiaxes(app.UIFigure);
            title(app.FsSpAxes, 'Two-Sided Linear Spectrum')
            xlabel(app.FsSpAxes, 'f')
            ylabel(app.FsSpAxes, 'S(f)')
            app.UpSigAxes = uiaxes(app.UIFigure);
            title(app.UpSigAxes, 'Up-Sampled-Signal')
            xlabel(app.UpSigAxes, 't')
            ylabel(app.UpSigAxes, 'S(t)')
            app.UpSpAxes = uiaxes(app.UIFigure);
            title(app.UpSpAxes, 'Two-Sided Linear Spectrum')
            xlabel(app.UpSpAxes, 'f')
            ylabel(app.UpSpAxes, 'S(f)')
            app.DoSigAxes = uiaxes(app.UIFigure);
            title(app.DoSigAxes, 'Down-Sampled-Signal')
            xlabel(app.DoSigAxes, 't')
            ylabel(app.DoSigAxes, 'S(t)')
            app.DoSpAxes = uiaxes(app.UIFigure);
            title(app.DoSpAxes, 'Two-Sided Linear Spectrum')
            xlabel(app.DoSpAxes, 'f')
            ylabel(app.DoSpAxes, 'S(f)')
            app.FsLSlider = uislider(app.UIFigure);
            app.FsLSlider.Limits = [1 21];
            app.FsLSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21];
            app.FsLSlider.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '21'};
            app.FsLSlider.ValueChangedFcn = createCallbackFcn(app, @FsLValueChanged, true);
            app.FsLSlider.MinorTicks = [];
            app.FsLSlider.Value = 1;
            app.FourierSeriesLengthLabel = uilabel(app.UIFigure);
            app.FourierSeriesLengthLabel.Text = 'Fourier-Series-Length';
            app.UpFacSlider = uislider(app.UIFigure);
            app.UpFacSlider.Limits = [1 10];
            app.UpFacSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.UpFacSlider.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '10'};
            app.UpFacSlider.ValueChangedFcn = createCallbackFcn(app, @UpFacValueChanged, true);
            app.UpFacSlider.MinorTicks = [];
            app.UpFacSlider.Enable = 'off';
            app.UpFacSlider.Value = 1;
            app.UpSamplingFactorLabel = uilabel(app.UIFigure);
            app.UpSamplingFactorLabel.Text = 'Up-Sampling-Factor';
            app.DowFacSlider = uislider(app.UIFigure);
            app.DowFacSlider.Limits = [1 10];
            app.DowFacSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.DowFacSlider.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '10'};
            app.DowFacSlider.ValueChangedFcn = createCallbackFcn(app, @DowFacValueChanged, true);
            app.DowFacSlider.MinorTicks = [];
            app.DowFacSlider.Enable = 'off';
            app.DowFacSlider.Value = 1;
            app.DownSamplingFactorLabel = uilabel(app.UIFigure);
            app.DownSamplingFactorLabel.Text = 'Down-Sampling-Factor';
            app.InterpolationCheckBox = uicheckbox(app.UIFigure);
            app.InterpolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntCheckBoxValueChanged, true);
            app.InterpolationCheckBox.Text = 'Interpolation';
            app.DecimationCheckBox = uicheckbox(app.UIFigure);
            app.DecimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DecCheckBoxValueChanged, true);
            app.DecimationCheckBox.Text = 'Decimation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = demoresample

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end